classdef RecieverApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RecieverUIFigure              matlab.ui.Figure
        RESIEVERLabel                 matlab.ui.control.Label
        FileAddressLabel              matlab.ui.control.Label
        FileAddressEditField          matlab.ui.control.EditField
        BrowseButton                  matlab.ui.control.Button
        UnderLine                     matlab.ui.control.Label
        ResieverSettingsPanel         matlab.ui.container.Panel
        FrequencyEditFieldLabel       matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        SymbolRateEditFieldLabel      matlab.ui.control.Label
        SymbolRateEditField           matlab.ui.control.NumericEditField
        FECDropDownLabel              matlab.ui.control.Label
        FECDropDown                   matlab.ui.control.DropDown
        DemodulatorLabel              matlab.ui.control.Label
        DemodulatorDropDown           matlab.ui.control.DropDown
        NumofFwddelaysEditFieldLabel  matlab.ui.control.Label
        NumofFwddelaysEditField       matlab.ui.control.NumericEditField
        NumofBwddelaysEditFieldLabel  matlab.ui.control.Label
        NumofBwddelaysEditField       matlab.ui.control.NumericEditField
        LambdaEditFieldLabel          matlab.ui.control.Label
        LambdaEditField               matlab.ui.control.NumericEditField
        ConvMtrxEditFieldLabel        matlab.ui.control.Label
        ConvMtrxEditField             matlab.ui.control.NumericEditField
        RecievedTextTextAreaLabel     matlab.ui.control.Label
        RecievedTextTextArea          matlab.ui.control.TextArea
        CloseButton                   matlab.ui.control.Button
        proccessButton                matlab.ui.control.Button
        ResievedsignalspecPanel       matlab.ui.container.Panel
        QualityLabel                  matlab.ui.control.Label
        StrengthLabel                 matlab.ui.control.Label
        Lamp_6                        matlab.ui.control.Lamp
        Lamp_5                        matlab.ui.control.Lamp
        Lamp_4                        matlab.ui.control.Lamp
        Lamp_3                        matlab.ui.control.Lamp
        Lamp_2                        matlab.ui.control.Lamp
        Lamp                          matlab.ui.control.Lamp
        Lamp_7                        matlab.ui.control.Lamp
        Lamp_8                        matlab.ui.control.Lamp
        Lamp_9                        matlab.ui.control.Lamp
        Lamp_10                       matlab.ui.control.Lamp
        Lamp_11                       matlab.ui.control.Lamp
        Lamp_12                       matlab.ui.control.Lamp
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end


    methods (Access = public)
    
        function refreshgui(app,strength,QOS,MSE)
            biase=0;
            app.Lamp.Visible = 'off';
            app.Lamp_2.Visible = 'off';
            app.Lamp_3.Visible = 'off';
            app.Lamp_4.Visible = 'off';
            app.Lamp_5.Visible = 'off';
            app.Lamp_6.Visible = 'off';
            app.Lamp_7.Visible = 'off';
            app.Lamp_8.Visible = 'off';
            app.Lamp_9.Visible = 'off';
            app.Lamp_10.Visible = 'off';
            app.Lamp_11.Visible = 'off';
            app.Lamp_12.Visible = 'off';
            app.Label.Visible='on';
            app.Label_2.Visible='on';
            app.Label.Text=[num2str(strength),' dB'];
            app.Label_2.Text=[num2str(QOS),' %'];
            if strength>-80+biase;app.Lamp.Visible = 'on' ; end
            if strength>-75+biase;app.Lamp_2.Visible = 'on';end
            if strength>-60+biase;app.Lamp_3.Visible = 'on';end
            if strength>-55+biase;app.Lamp_4.Visible = 'on';end
            if strength>-50+biase;app.Lamp_5.Visible = 'on';end
            if strength>-40+biase;app.Lamp_6.Visible = 'on';end
            if QOS>55;app.Lamp_12.Visible = 'on'; end
            if QOS>60;app.Lamp_11.Visible = 'on';end
            if QOS>70;app.Lamp_10.Visible = 'on';end
            if QOS>80;app.Lamp_9.Visible = 'on';end
            if QOS>90;app.Lamp_8.Visible = 'on';end
            if QOS>95;app.Lamp_7.Visible = 'on';end
            if ~isempty(MSE)
                plot(app.UIAxes,1:length(MSE),MSE);
            end
        end       
    end


    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseFunc(app, event)
                 [a,app.FileAddressEditField.Value] = uigetfile({'*.wav;*.m4a'});
                 app.FileAddressEditField.Value=[app.FileAddressEditField.Value,a];
                 app.RecieverUIFigure.Visible='off';
                 app.RecieverUIFigure.Visible='on';
        end

        % Button pushed function: CloseButton
        function Close(app, event)
                 clc;
                 closereq; 
        end

        % Value changed function: DemodulatorDropDown
        function DemodDrop(app, event)
            value = app.DemodulatorDropDown.Value;
            if value=='1'
                app.NumofFwddelaysEditFieldLabel.Visible = 'on';
                app.NumofFwddelaysEditField.Visible = 'on';
                app.LambdaEditFieldLabel.Visible = 'on';
                app.LambdaEditField.Visible = 'on';
                app.NumofBwddelaysEditFieldLabel.Visible = 'on';
                app.NumofBwddelaysEditField.Visible = 'on';
                app.ConvMtrxEditFieldLabel.Visible = 'on';
                app.ConvMtrxEditField.Visible = 'on';
                app.LambdaEditField.Value=1;
            elseif value=='2'
                app.NumofFwddelaysEditFieldLabel.Visible = 'on';
                app.NumofFwddelaysEditField.Visible = 'on';
                app.LambdaEditFieldLabel.Visible = 'on';
                app.LambdaEditField.Visible = 'on';
                app.NumofBwddelaysEditFieldLabel.Visible = 'on';
                app.NumofBwddelaysEditField.Visible = 'on';
                app.ConvMtrxEditFieldLabel.Visible = 'off';
                app.ConvMtrxEditField.Visible = 'off';
                app.LambdaEditField.Value=0.005;
                
            elseif value=='3'
                app.NumofFwddelaysEditFieldLabel.Visible = 'off';
                app.NumofFwddelaysEditField.Visible = 'off';
                app.LambdaEditFieldLabel.Visible = 'off';
                app.LambdaEditField.Visible = 'off';
                app.NumofBwddelaysEditFieldLabel.Visible = 'off';
                app.NumofBwddelaysEditField.Visible = 'off';
                app.ConvMtrxEditFieldLabel.Visible = 'off';
                app.ConvMtrxEditField.Visible = 'off'; 
            end
        end

        % Button pushed function: proccessButton
        function process(app, event)
               %% initial variables for other functions
            app.RecievedTextTextArea.Value=' ';
            if exist(app.FileAddressEditField.Value,'file')
                clc;
                clear DfeEqualizer;
                clear viterbi_GMSK_demod;
                MSE=[];
                CenterFreq=app.FrequencyEditField.Value;
                SymbolRate=app.SymbolRateEditField.Value;
                FEC=app.FECDropDown.Value;
                path=app.FileAddressEditField.Value;
                DemodMod=app.DemodulatorDropDown.Value;
                nfwdweights=app.NumofFwddelaysEditField.Value;
                nfbkweights=app.NumofBwddelaysEditField.Value;
                cons1=app.LambdaEditField.Value;
                cons2=app.ConvMtrxEditField.Value;
                ESS_sig=Chirp(CenterFreq,SymbolRate);
                %% loading signal and set variables
                Audio=audioread(path);
                Audio=Audio(:,1);
                if strcmp(DemodMod,'2');Audio=Audio./max(Audio);end
                SamplePerSymbol=floor(44100/SymbolRate);
                BaseGuard=SamplePerSymbol;
                %% Filter data
                Filter=fir1(64,[CenterFreq-0.5*SymbolRate,CenterFreq+0.5*SymbolRate]*2/44100);
                Audio=conv(Audio,Filter,'same');
                %% Syncronization Part1: find begining of symbols.
                [acor,lag] = xcorr(Audio,ESS_sig);
                [~,I] = max(acor);
                lagDiff = lag(I);
                disp('Syncing Frequency and time')
                %% Creating Train modulated signal for using in Equalizer and run Equalizer in Trainig mod
                Train=[1,1,1,0,0,0,1,0,1,0,1,0,0,0,1,0,0,1,1,1,1,0,1,1,0,1,0,0,1,...
                    1,1,0,1,0,0,0,0,1,0,1,0,1,0,1,1,1,0,0,0,1,0,1,1,1,0,0,0,1,0,1,1,1,0,0]';
                TrainSec=gmsk_mod(Train,SymbolRate);
                curser=lagDiff+88200;             
                %% Extract data Frames
                TrainCheck=0;
                TXT=[];
                SymbolsInFrame=64;
                while curser+(SymbolsInFrame*SamplePerSymbol)<=length(Audio)
                    if mod(TrainCheck,3)==0
                        TrainFlag=1;
                        TrainMod=TrainSec;
                        SymbolsInFrame=64;
                    else
                        TrainFlag=0;
                        TrainMod=[];
                        switch FEC
                           case '1/2'
                              SymbolsInFrame=466;
                           case '3/4'
                              SymbolsInFrame=316;
                           case '5/6'
                              SymbolsInFrame=286;
                        end
                    end
                    WinData=Audio((curser+1-BaseGuard):(curser+(SymbolsInFrame*SamplePerSymbol)+BaseGuard));
                    FrameBase=BasebandRecovery(WinData,CenterFreq,curser,BaseGuard);
                    switch DemodMod
                        case '1'
                            [DemodRes,MSE]=DfeEqualizer(FrameBase,...
                               SymbolRate,nfwdweights,nfbkweights,TrainMod,cons1,cons2);
                        case '2'
                               [DemodRes,MSE]=DfeEqualizer(FrameBase,...
                               SymbolRate,nfwdweights,nfbkweights,TrainMod,cons1,[]);
                        case '3'
                            if TrainFlag==1  % PHASE OF Carrier
                            CompensatePhi=TrainMod.'*conj(FrameBase);
                            CompensatePhi=CompensatePhi./norm(CompensatePhi);
                            end
                            FrameBase=FrameBase*CompensatePhi;
                            DemodRes=viterbi_GMSK_demod(FrameBase,SymbolRate);
                    end
                    %% reliability of frame
                    if TrainFlag==0 && sum(DemodRes([2:8,(SymbolsInFrame-7):SymbolsInFrame])==[1;0;0;1;0;1;0;0;1;0;0;1;0;0;1])>= 10
                        DecData=MessageDecoder(DemodRes(9:end-8),FEC);
                        TEXTDEC=[128 64 32 16 8 4 2 1]*double(DecData');
                        TXT=[TXT,char(TEXTDEC)];
                        disp(['Frame # ',num2str(TrainCheck-floor(TrainCheck/3)),' Recieved successfully']);
                        app.RecievedTextTextArea.Value=TXT;
                    elseif TrainFlag==1
                        QOS=(sum(DemodRes(3:62)==Train(3:62))/60)*100; % should be near 100.  50 means Equalizer doesn't work properly
                        if QOS>80;disp('Training signal Decodeded successfully');else; disp('Failed to Decode Training'); end
                    else
                        disp('Failed to decode frame')
                    end
                    strength=10*log10(WinData'*WinData/(length(WinData)));  
                    refreshgui(app,strength,QOS,MSE);   
                    pause(0.1)
                    curser=curser+(SymbolsInFrame*SamplePerSymbol);
                    TrainCheck=TrainCheck+1;
                end
            else
                msgbox('Path doesn''t Exist','Error','error')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RecieverUIFigure = uifigure;
            app.RecieverUIFigure.Name = 'Reciever';
            app.RESIEVERLabel = uilabel(app.RecieverUIFigure);
            app.RESIEVERLabel.Text = 'RESIEVER';
            app.FileAddressLabel = uilabel(app.RecieverUIFigure);
            app.FileAddressLabel.Text = 'File Address :';
            app.FileAddressEditField = uieditfield(app.RecieverUIFigure, 'text');
            app.FileAddressEditField.Value = '5K_200s_3_4.wav';
            app.BrowseButton = uibutton(app.RecieverUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFunc, true);
            app.BrowseButton.Text = 'Browse';
            app.UnderLine = uilabel(app.RecieverUIFigure);
            app.UnderLine.Text = '_______________________________';
            app.ResieverSettingsPanel = uipanel(app.RecieverUIFigure);
            app.ResieverSettingsPanel.Title = 'Resiever Settings';
            app.FrequencyEditFieldLabel = uilabel(app.ResieverSettingsPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.ResieverSettingsPanel, 'numeric');
            app.FrequencyEditField.Limits = [2000 18000];
            app.FrequencyEditField.Value = 5000;
            app.SymbolRateEditFieldLabel = uilabel(app.ResieverSettingsPanel);
            app.SymbolRateEditFieldLabel.Text = 'SymbolRate';
            app.SymbolRateEditField = uieditfield(app.ResieverSettingsPanel, 'numeric');
            app.SymbolRateEditField.Limits = [10 1000];
            app.SymbolRateEditField.Value = 200;
            app.FECDropDownLabel = uilabel(app.ResieverSettingsPanel);
            app.FECDropDownLabel.Text = 'FEC';
            app.FECDropDown = uidropdown(app.ResieverSettingsPanel);
            app.FECDropDown.Items = {'1/2', '3/4', '5/6'};
            app.FECDropDown.Value = '3/4';
            app.DemodulatorLabel = uilabel(app.ResieverSettingsPanel);
            app.DemodulatorLabel.Text = 'Demodulator:';
            app.DemodulatorDropDown = uidropdown(app.ResieverSettingsPanel);
            app.DemodulatorDropDown.Items = {'DFE-RLS', 'DFE-LMS', 'Without Equalizer'};
            app.DemodulatorDropDown.ItemsData = {'1', '2', '3'};
            app.DemodulatorDropDown.ValueChangedFcn = createCallbackFcn(app, @DemodDrop, true);
            app.DemodulatorDropDown.Value = '1';
            app.NumofFwddelaysEditFieldLabel = uilabel(app.ResieverSettingsPanel);
            app.NumofFwddelaysEditFieldLabel.Text = 'Num of Fwd delays';
            app.NumofFwddelaysEditField = uieditfield(app.ResieverSettingsPanel, 'numeric');
            app.NumofFwddelaysEditField.Limits = [1 100];
            app.NumofFwddelaysEditField.Value = 20;
            app.NumofBwddelaysEditFieldLabel = uilabel(app.ResieverSettingsPanel);
            app.NumofBwddelaysEditFieldLabel.Text = 'Num of Bwd delays';
            app.NumofBwddelaysEditField = uieditfield(app.ResieverSettingsPanel, 'numeric');
            app.NumofBwddelaysEditField.Limits = [1 100];
            app.NumofBwddelaysEditField.Value = 20;
            app.LambdaEditFieldLabel = uilabel(app.ResieverSettingsPanel);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.ResieverSettingsPanel, 'numeric');
            app.LambdaEditField.Value = 1;
            app.ConvMtrxEditFieldLabel = uilabel(app.ResieverSettingsPanel);
            app.ConvMtrxEditFieldLabel.Text = 'ConvMtrx:';
            app.ConvMtrxEditField = uieditfield(app.ResieverSettingsPanel, 'numeric');
            app.ConvMtrxEditField.Value = 1000;
            app.RecievedTextTextAreaLabel = uilabel(app.RecieverUIFigure);
            app.RecievedTextTextAreaLabel.Text = 'Recieved Text:';
            app.RecievedTextTextArea = uitextarea(app.RecieverUIFigure);
            app.CloseButton = uibutton(app.RecieverUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @Close, true);
            app.CloseButton.Text = 'Close';
            app.proccessButton = uibutton(app.RecieverUIFigure, 'push');
            app.proccessButton.ButtonPushedFcn = createCallbackFcn(app, @process, true);
            app.proccessButton.Text = 'proccess';
            app.ResievedsignalspecPanel = uipanel(app.RecieverUIFigure);
            app.ResievedsignalspecPanel.Title = 'Resieved signal spec.';
            app.QualityLabel = uilabel(app.ResievedsignalspecPanel);
            app.QualityLabel.Text = 'Quality:';
            app.StrengthLabel = uilabel(app.ResievedsignalspecPanel);
            app.StrengthLabel.Text = 'Strength:';
            app.Lamp_6 = uilamp(app.ResievedsignalspecPanel);
            app.Lamp_6.Visible = 'off';
            app.Lamp_5 = uilamp(app.ResievedsignalspecPanel);
            app.Lamp_5.Visible = 'off';
            app.Lamp_4 = uilamp(app.ResievedsignalspecPanel);
            app.Lamp_4.Visible = 'off';
            app.Lamp_3 = uilamp(app.ResievedsignalspecPanel);
            app.Lamp_3.Visible = 'off';
            app.Lamp_2 = uilamp(app.ResievedsignalspecPanel);
            app.Lamp_2.Visible = 'off';
            app.Lamp = uilamp(app.ResievedsignalspecPanel);
            app.Lamp.Visible = 'off';
            app.Lamp_7 = uilamp(app.ResievedsignalspecPanel);
            app.Lamp_7.Visible = 'off';
            app.Lamp_8 = uilamp(app.ResievedsignalspecPanel);
            app.Lamp_8.Visible = 'off';
            app.Lamp_9 = uilamp(app.ResievedsignalspecPanel);
            app.Lamp_9.Visible = 'off';
            app.Lamp_10 = uilamp(app.ResievedsignalspecPanel);
            app.Lamp_10.Visible = 'off';
            app.Lamp_11 = uilamp(app.ResievedsignalspecPanel);
            app.Lamp_11.Visible = 'off';
            app.Lamp_12 = uilamp(app.ResievedsignalspecPanel);
            app.Lamp_12.Visible = 'off';
            app.Label = uilabel(app.ResievedsignalspecPanel);
            app.Label.Visible = 'off';
            app.Label_2 = uilabel(app.ResievedsignalspecPanel);
            app.Label_2.Visible = 'off';
            app.UIAxes = uiaxes(app.RecieverUIFigure);
            title(app.UIAxes, 'MSE')
            xlabel(app.UIAxes, 'signal samples')
            ylabel(app.UIAxes, 'Value')
        end
    end

    methods (Access = public)

        % Construct app
        function app = RecieverApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RecieverUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RecieverUIFigure)
        end
    end
end