classdef TransmitterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TransmitterUIFigure           matlab.ui.Figure
        TransmitterLabel              matlab.ui.control.Label
        FrequencyHzEditFieldLabel     matlab.ui.control.Label
        FrequencyHzEditField          matlab.ui.control.NumericEditField
        SymbolRatessEditFieldLabel    matlab.ui.control.Label
        SymbolRatessEditField         matlab.ui.control.NumericEditField
        EnteryourmessagehereTextAreaLabel  matlab.ui.control.Label
        EnteryourmessagehereTextArea  matlab.ui.control.TextArea
        FECDropDownLabel              matlab.ui.control.Label
        FECDropDown                   matlab.ui.control.DropDown
        closeButton                   matlab.ui.control.Button
        startButton                   matlab.ui.control.Button
        LiveSpecButton                matlab.ui.control.Button
        FilenameEditFieldLabel        matlab.ui.control.Label
        FilenameEditField             matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: LiveSpecButton
        function LiveSpec(app, event)
            recorder = audiorecorder(44100,8,1);
            scope = dsp.SpectrumAnalyzer('SampleRate',44100);
            scope.PlotAsTwoSidedSpectrum = false;
            Flag=1;
            while Flag
            recordblocking(recorder, 0.1);
            y = getaudiodata(recorder);
            scope(y)
            Flag=scope.isVisible;
            end
        end

        % Button pushed function: closeButton
        function close(app, event)
            clc;
            closereq;
        end

        % Button pushed function: startButton
        function start(app, event)
                CenterFreq=app.FrequencyHzEditField.Value;
                SymbolRate=app.SymbolRatessEditField.Value;
                FEC=app.FECDropDown.Value;
                HEX=app.EnteryourmessagehereTextArea.Value;
                HEX=HEX{1,1}+0;
                HEX=dec2hex(HEX);
                [MsgSize,~]=size(HEX);
                HEX=[HEX;repmat('20',18*ceil(MsgSize/18)-MsgSize,1)];
                NumberOfBurtsts=ceil(MsgSize/18);
                NumBurst=1;  % Number Of Bursts;
                ModStCnt=[]; % Modulator State Control (Modulator PhaseOffset Should be zero at the beginning of each Burst)
                while NumBurst<=NumberOfBurtsts
                    Burst=HEX((18*(NumBurst-1)+1):18*NumBurst,:); % Each burst contains 18 Bytes
                    CodedData=MessageCoder(Burst,FEC);
                    ModStCnt=mod(2*sum(CodedData)-length(CodedData),4)/2;
                    BasebandBits(:,NumBurst)=[ModStCnt;1;0;0;1;0;1;0;CodedData;0;1;0;0;1;0;0;1]; % padding zeros at two sides of bursts
                    NumBurst=NumBurst+1;
                end
                %% Channel estimate train signal
                ESS=CHIRP(CenterFreq,SymbolRate);
                Train=[1,1,1,0,0,0,1,0,1,0,1,0,0,0,1,0,0,1,1,1,1,0,1,1,0,1,0,0,1,...
                    1,1,0,1,0,0,0,0,1,0,1,0,1,0,1,1,1,0,0,0,1,0,1,1,1,0,0,0,1,0,1,1,1,0,0]';
                AllBits=[];
                for j=1:NumberOfBurtsts
                    if mod(j,2)
                        AllBits=[AllBits;Train;BasebandBits(:,j)];
                    else
                        AllBits=[AllBits;BasebandBits(:,j)];
                    end
                end
                %% modulate BaseBandBits
                SigBase=gmsk_mod(double(AllBits),SymbolRate);
                %% Adding train data for channel estimation
                t=(0:1:(length(SigBase)-1))'./44100;
                MsgTx=SigBase.*exp(1i*(2*pi*CenterFreq*t));
                PhisycalSignal=[zeros(22050,1);ESS;real(MsgTx);zeros(22050,1)];
                audiowrite(app.FilenameEditField.Value,PhisycalSignal,44100); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TransmitterUIFigure = uifigure;
            app.TransmitterUIFigure.Name = 'Transmitter';
            app.TransmitterLabel = uilabel(app.TransmitterUIFigure);
            app.TransmitterLabel.Text = 'Transmitter';
            app.FrequencyHzEditFieldLabel = uilabel(app.TransmitterUIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.TransmitterUIFigure, 'numeric');
            app.FrequencyHzEditField.Limits = [2000 18000];
            app.FrequencyHzEditField.Value = 5000;
            app.SymbolRatessEditFieldLabel = uilabel(app.TransmitterUIFigure);
            app.SymbolRatessEditFieldLabel.Text = 'SymbolRate (s/s)';
            app.SymbolRatessEditField = uieditfield(app.TransmitterUIFigure, 'numeric');
            app.SymbolRatessEditField.Limits = [10 1000];
            app.SymbolRatessEditField.Value = 200;
            app.EnteryourmessagehereTextAreaLabel = uilabel(app.TransmitterUIFigure);
            app.EnteryourmessagehereTextAreaLabel.Text = 'Enter your message here';
            app.EnteryourmessagehereTextArea = uitextarea(app.TransmitterUIFigure);
            app.FECDropDownLabel = uilabel(app.TransmitterUIFigure);
            app.FECDropDownLabel.Text = 'FEC';
            app.FECDropDown = uidropdown(app.TransmitterUIFigure);
            app.FECDropDown.Items = {'1/2', '3/4', '5/6'};
            app.FECDropDown.Value = '3/4';
            app.closeButton = uibutton(app.TransmitterUIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @close, true);
            app.closeButton.Text = 'close';
            app.startButton = uibutton(app.TransmitterUIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.startButton.Text = 'start';
            app.LiveSpecButton = uibutton(app.TransmitterUIFigure, 'push');
            app.LiveSpecButton.ButtonPushedFcn = createCallbackFcn(app, @LiveSpec, true);
            app.LiveSpecButton.Text = 'Live Spec';
            app.FilenameEditFieldLabel = uilabel(app.TransmitterUIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.TransmitterUIFigure, 'text');
            app.FilenameEditField.Value = '5K_200s_3_4.wav';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TransmitterApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TransmitterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TransmitterUIFigure)
        end
    end
end